classdef CrystalThing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        ViewTab                   matlab.ui.container.Tab
        ArrangementsListBoxLabel  matlab.ui.control.Label
        Arrangements              matlab.ui.control.ListBox
        ViewModeButtonGroup       matlab.ui.container.ButtonGroup
        GradientButton            matlab.ui.control.ToggleButton
        SolidButton               matlab.ui.control.ToggleButton
        LowPolyButton             matlab.ui.control.ToggleButton
        MillerIndiciesPanel       matlab.ui.container.Panel
        MillerDirectionsLabel     matlab.ui.control.Label
        MillerPlanesLabel         matlab.ui.control.Label
        x                         matlab.ui.control.NumericEditField
        y                         matlab.ui.control.NumericEditField
        z                         matlab.ui.control.NumericEditField
        j                         matlab.ui.control.NumericEditField
        k                         matlab.ui.control.NumericEditField
        l                         matlab.ui.control.NumericEditField
        DrawDirection             matlab.ui.control.Button
        EraseDirection            matlab.ui.control.Button
        DrawPlane                 matlab.ui.control.Button
        ErasePlane                matlab.ui.control.Button
        ArrangementInformationTextAreaLabel  matlab.ui.control.Label
        InfoText                  matlab.ui.control.TextArea
        ExtrainfoCheckBox         matlab.ui.control.CheckBox
        TextCheckBox              matlab.ui.control.CheckBox
        UnitCellButtonGroup       matlab.ui.container.ButtonGroup
        NoneButton                matlab.ui.control.ToggleButton
        EdgesButton               matlab.ui.control.ToggleButton
        FacesButton               matlab.ui.control.ToggleButton
        AtomLabel                 matlab.ui.control.Label
        Atoms                     matlab.ui.control.ListBox
        ResetButton               matlab.ui.control.Button
        ViewAxes                  matlab.ui.control.UIAxes
        CreateTab                 matlab.ui.container.Tab
        AtomsSettingsPanel        matlab.ui.container.Panel
        PosittionLabel            matlab.ui.control.Label
        RadiusLabel               matlab.ui.control.Label
        pos_x                     matlab.ui.control.NumericEditField
        pos_y                     matlab.ui.control.NumericEditField
        pos_z                     matlab.ui.control.NumericEditField
        r                         matlab.ui.control.NumericEditField
        AddButton                 matlab.ui.control.Button
        NameLabel                 matlab.ui.control.Label
        NameText                  matlab.ui.control.EditField
        UnitCellSettingsPanel     matlab.ui.container.Panel
        LengthsLabel              matlab.ui.control.Label
        AnglesLabel               matlab.ui.control.Label
        length_1                  matlab.ui.control.NumericEditField
        length_2                  matlab.ui.control.NumericEditField
        length_3                  matlab.ui.control.NumericEditField
        angle_1                   matlab.ui.control.NumericEditField
        angle_2                   matlab.ui.control.NumericEditField
        angle_3                   matlab.ui.control.NumericEditField
        SetButton                 matlab.ui.control.Button
        SavePanel                 matlab.ui.container.Panel
        SaveButton                matlab.ui.control.Button
        NameLabel_2               matlab.ui.control.Label
        NameText_2                matlab.ui.control.EditField
        NewButton                 matlab.ui.control.Button
        CreateAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedPath; % Description
        viewMode = "gradient";
        maxX = 0;
        maxY = 0;
        maxZ = 0;
        angleX = 0;
        angleY = 0;
        angleZ = 0;
        directions = [];
        plane;
        extraInfo = [];
        atoms = [];
        textUI = [];
        atomPositions = [];
        radiusAtoms = [];
        transformation = [];
        createTransformation = [];
        edgesPositions = [0,0,0,1,0,0;0,1,0,1,1,0;0,0,1,1,0,1;0,1,1,1,1,1;
                 0,0,0,0,1,0;1,0,0,1,1,0;0,0,1,0,1,1;1,0,1,1,1,1;
                 0,0,0,0,0,1;0,1,0,0,1,1;1,0,0,1,0,1;1,1,0,1,1,1;];
        facesPositions = [1,0,0;-1,0,0;
                 0,1,0;0,-1,0;
                 0,0,1;0,0,-1];
        edges = [];
        createEdges = [];
        faces = [];
        editAtomsPos = [];
        editAtomsName = [];
        sideLengths = [];
    end
    
    methods (Access = private)
        
        function plot = splot(app,radius,x,y,z) 
            [X,Y,Z] = sphere;
            plot = surf(app.ViewAxes,X*radius + x, Y*radius + y, Z*radius + z);
            axis(app.ViewAxes, 'equal');
        end
        
        function [maxX, maxY, maxZ] = drawLattice(app,data,textOn,textData,sideData)
            check = isnan(data);
            maxX = sideData(1);
            maxY = sideData(2);
            maxZ = sideData(3);
            for idx = 1:(length(data(:))/4)
                if check(4*(idx-1)+1) == 1
                    break;
                end
                radius = data(4*(idx-1)+1);
                x_1 = data(4*(idx-1)+2);
                y_1 = data(4*(idx-1)+3);
                z_1 = data(4*(idx-1)+4);
                s = splot(app,radius,x_1,y_1,z_1);
                
                if textOn
                    t = text(app.ViewAxes,x_1,y_1,z_1,textData(2*(idx-1)+1), 'HorizontalAlignment', 'center');
                    app.textUI = [app.textUI, t];
                end
                
                if(idx==1)
                    hold(app.ViewAxes, 'on');
                end
                
                set(s, 'FaceColor', 'interp', 'EdgeColor', 'none', ...
                    'FaceAlpha', 0.3, 'LineStyle', ':');
                app.atoms = [app.atoms, s];
                app.atomPositions = [app.atomPositions; [x_1,y_1,z_1]];
                app.radiusAtoms = [app.radiusAtoms, radius];
            end
            
            %extra info
            a = plot3(app.ViewAxes,[0, maxX], [0, 0], [0, 0], 'k-', 'LineWidth', 1);
            a_text = text(app.ViewAxes,maxX/2,0,0,'x', 'HorizontalAlignment', 'center');
            
            b = plot3(app.ViewAxes,[0, 0], [0, maxY], [0, 0], 'k-', 'LineWidth', 1);
            b_text = text(app.ViewAxes,0,maxY/2,0,'y', 'HorizontalAlignment', 'center');
            
            c = plot3(app.ViewAxes,[0, 0], [0, 0], [0, maxZ], 'k-', 'LineWidth', 1);
            c_text = text(app.ViewAxes,0,0,maxZ/2,'z', 'HorizontalAlignment', 'center');
            
            app.extraInfo = [a,a_text,b,b_text,c,c_text];
            
            hold(app.ViewAxes, 'off');
        end
        
        function millerDirection(app,millerD,keepHold)
            if keepHold
                hold(app.ViewAxes, 'on');
            end
            
            maxD = max(abs(millerD));
            millerD = millerD/maxD;
            startCoord = [0;0;0];
            endCoord = millerD';
            
            %Keep direction inside unit cell drawn
            if endCoord(1) < 0
                startCoord(1) = startCoord(1) + 1;
                endCoord(1) = endCoord(1) + 1;
            end
            if endCoord(2) < 0
                startCoord(2) = startCoord(2) + 1;
                endCoord(2) = endCoord(2) + 1;
            end
            if endCoord(3) < 0
                startCoord(3) = startCoord(3) + 1;
                endCoord(3) = endCoord(3) + 1;
            end
            
            startCoord = app.transformation*startCoord;
            endCoord = app.transformation*endCoord;
            
            q = quiver3(app.ViewAxes,startCoord(1),startCoord(2),startCoord(3),...
                (endCoord(1)-startCoord(1)),(endCoord(2)-startCoord(2)),(endCoord(3)-startCoord(3)),0,...
                'LineWidth', 1.5);
            
            app.directions = [app.directions, q];
            
            if keepHold == false
                hold(app.ViewAxes, 'on');
            end
            
            hold(app.ViewAxes, 'off');
        end
        
        function millerPlane(app,millerP,keepHold)
            millerP = 1./millerP;
            x_1 = [millerP(1),0,0];
            y_1 = [0,millerP(2),0];
            z_1 = [0,0,millerP(3)];
            
            if millerP(1) < 0
                x_1 = x_1 + 1;
            end
            if millerP(2) < 0
                y_1 = y_1 + 1;
            end
            if millerP(3) < 0
                z_1 = z_1 + 1;
            end
            
            infD = ~isinf(millerP);
            
            x_1 = x_1(infD == 1);
            y_1 = y_1(infD == 1);
            z_1 = z_1(infD == 1);
            
            transformationD = app.transformation;
            
            if length(x_1) == 2
                points = [x_1;y_1;z_1];
                points = [points,flip(points,2)];
                
                points(:,3:4) = points(:,3:4) + ones(3,2).*(infD == 0)';
                
                points(:,1) = (transformationD*points(:,1));
                points(:,2) = (transformationD*points(:,2));
                points(:,3) = (transformationD*points(:,3));
                points(:,4) = (transformationD*points(:,4));
            elseif length(x_1) == 1
                points = [x_1;y_1;z_1];
                points = [points,points,points,points];
                
                count = 0;
                for idx = 1:length(infD)
                    if infD(idx) == 0
                        if count == 0
                            copy = infD;
                            copy(idx) = 1;
                            points(:,2:3) = points(:,2:3) + ones(3,2).*(copy == 0)';
                        else
                            copy = infD;
                            copy(idx) = 1;
                            points(:,3:4) = points(:,3:4) + ones(3,2).*(copy == 0)';
                        end
                        count = count + 1;
                    end
                end
                
                points(:,1) = (transformationD*points(:,1));
                points(:,2) = (transformationD*points(:,2));
                points(:,3) = (transformationD*points(:,3));
                points(:,4) = (transformationD*points(:,4));
            else
                points = [x_1;y_1;z_1];

                points(:,1) = (transformationD*points(:,1));
                points(:,2) = (transformationD*points(:,2));
                points(:,3) = (transformationD*points(:,3));
            end
            
            x_1 = points(1,:);
            y_1 = points(2,:);
            z_1 = points(3,:);
            
            if keepHold
                hold(app.ViewAxes, 'on');
            end
            
            p = patch(app.ViewAxes,'XData',x_1,'YData',y_1,'ZData',z_1,'EdgeColor', 'none', 'FaceAlpha', 0.4);
            app.plane = p;
            
            if keepHold
                hold(app.ViewAxes, 'off');
            end
        end
        
        function drawEdges(app)
            for idx = 1:length(app.edgesPositions(:,1))
                hold(app.ViewAxes, 'on');
                
                startCoord = app.transformation*app.edgesPositions(idx,1:3)';
                endCoord = app.transformation*app.edgesPositions(idx,4:6)';
                
                coords = [startCoord';endCoord'];
                
                e = plot3(app.ViewAxes,coords(:,1),coords(:,2),coords(:,3),'-k','LineWidth', 1.2, 'Visible', 'off');
                
                app.edges = [app.edges, e];

                hold(app.ViewAxes, 'off');
            end
        end
        
        function drawFaces(app)
            for idx = 1:length(app.facesPositions(:,1))
                millerP = 1./app.facesPositions(idx,:);
                x_1 = [millerP(1),0,0];
                y_1 = [0,millerP(2),0];
                z_1 = [0,0,millerP(3)];
                
                if millerP(1) < 0
                    x_1 = x_1 + 1;
                end
                if millerP(2) < 0
                    y_1 = y_1 + 1;
                end
                if millerP(3) < 0
                    z_1 = z_1 + 1;
                end
                
                infD = ~isinf(millerP);
                
                x_1 = x_1(infD == 1);
                y_1 = y_1(infD == 1);
                z_1 = z_1(infD == 1);
                
                transformationD = app.transformation;
                
                points = [x_1;y_1;z_1];
                points = [points,points,points,points];
                
                count = 0;
                for idx2 = 1:length(infD)
                    if infD(idx2) == 0
                        if count == 0
                            copy = infD;
                            copy(idx2) = 1;
                            points(:,2:3) = points(:,2:3) + ones(3,2).*(copy == 0)';
                        else
                            copy = infD;
                            copy(idx2) = 1;
                            points(:,3:4) = points(:,3:4) + ones(3,2).*(copy == 0)';
                        end
                        count = count + 1;
                    end
                end
                
                points(:,1) = (transformationD*points(:,1));
                points(:,2) = (transformationD*points(:,2));
                points(:,3) = (transformationD*points(:,3));
                points(:,4) = (transformationD*points(:,4));
                
                x_1 = points(1,:);
                y_1 = points(2,:);
                z_1 = points(3,:);

                hold(app.ViewAxes, 'on');
                
                p = patch(app.ViewAxes,'XData',x_1,'YData',y_1,'ZData',z_1,'EdgeColor', 'none', 'FaceAlpha', 0.4, 'Visible', 'off');
                app.faces(idx) = p;

                hold(app.ViewAxes, 'off');
            end
        end
        
        function drawDefaultAtoms(app)
            selectedButton = app.ViewModeButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Solid"
                for idx = 1:length(app.atoms)
                    set(app.atoms(idx), 'FaceColor', 'r', 'EdgeColor', 'none');
                end
            elseif selectedButton.Text == "Gradient"
                for idx = 1:length(app.atoms)
                    set(app.atoms(idx), 'FaceColor', 'interp', 'EdgeColor', 'none');
                end
            else
                for idx = 1:length(app.atoms)
                    set(app.atoms(idx), 'FaceColor', 'r', 'EdgeColor', 'r');
                end
            end
        end
        
        function selectedAtoms(app)
            value = app.Atoms.Value;
            
            drawDefaultAtoms(app);
            
            for idx = 1:length(value)
                pos = 0;
                for idx2 = 1:length(app.textUI)
                    if string(value(idx)) == app.textUI(idx2).String
                        pos = idx2;
                        break;
                    end
                end
                set(app.atoms(pos), 'FaceColor', 'g', 'EdgeColor', 'none');
            end
        end
        
        function drawUnitCell(app)
            hold(app.CreateAxes, 'on');
            for idx = 1:length(app.edgesPositions(:,1))
                startCoord = app.createTransformation*app.edgesPositions(idx,1:3)';
                endCoord = app.createTransformation*app.edgesPositions(idx,4:6)';
                
                coords = [startCoord';endCoord'];
                
                e = plot3(app.CreateAxes,coords(:,1),coords(:,2),coords(:,3),'-k','LineWidth', 1.2, 'Visible', 'on');
                
                app.createEdges = [app.createEdges, e];
            end
            
            %[X,Y,Z] = sphere;
            %surf(app.CreateAxes,X*0.2, Y*0.2, Z*0.2);
            %axis(app.CreateAxes, 'equal');
            rotate3d(app.CreateAxes, 'on');
            view(app.CreateAxes, [-35 30])
            
            hold(app.CreateAxes, 'off');
        end
        
        function makeTransfMatrix(app)
            theta = (90-app.angle_3.Value)/2;
            X = [1 sind(theta) 0]*app.length_1.Value;
            Y = [sind(theta) 1 0]*app.length_2.Value;
            Z = [cosd(app.angle_1.Value)*cosd(theta) cosd(app.angle_2.Value)*cosd(theta) 1]*app.length_3.Value;
            app.sideLengths = [app.length_1.Value, app.length_2.Value, app.length_3.Value];
            
            app.createTransformation = [X' Y' Z'];
        end
        
        function plot = eplot(app,radius,x,y,z) 
            hold(app.CreateAxes, 'on');
            [X,Y,Z] = sphere;
            plot = surf(app.CreateAxes,X*radius + x, Y*radius + y, Z*radius + z);
            axis(app.CreateAxes, 'equal');
            hold(app.CreateAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentDir = pwd;
            
            selected = dir(fullfile(currentDir, '*.csv'));

            app.Arrangements.Items = {selected(:).name};
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.selectedPath = uigetdir();
            selected = dir(fullfile(app.selectedPath, '*.csv'));

            app.Arrangements.Items = {selected(:).name};
        end

        % Value changed function: Arrangements
        function ArrangementsValueChanged(app, event)
            value = app.Arrangements.Value;
            
            file = fullfile(app.selectedPath, value);
            
            A = importdata(file,',',1);
            
            app.UnitCellButtonGroup.SelectedObject = app.NoneButton;
            app.ViewModeButtonGroup.SelectedObject = app.GradientButton;
            app.ExtrainfoCheckBox.Value = true;
            app.TextCheckBox.Value = true;
            
            app.atoms = [];
            app.textUI = [];
            app.extraInfo = [];
            app.atomPositions = [];
            app.edges = [];
            app.faces = [];
            app.transformation = A.data(2:end-1,1:3);
            
            [app.maxX,app.maxY,app.maxZ] = drawLattice(app,A.data(1,:),true,char(A.textdata(:)),A.data(end,1:3));
            app.transformation = app.transformation.*[app.maxX,app.maxY,app.maxZ;...
                                 app.maxX,app.maxY,app.maxZ;...
                                 app.maxX,app.maxY,app.maxZ];
            
            textInfo = [];
            for idx = 1:length(app.atomPositions(:,1))
               rectifiedPosition = (app.transformation)\app.atomPositions(idx,:)';
               trash = (rectifiedPosition(:) > 1e-5);
               rectifiedPosition = rectifiedPosition.*trash;
               textInfo = [textInfo, join(["Position Atom",app.textUI(idx).String,"= [",...
                   string(rectifiedPosition(1)),",",...
                   string(rectifiedPosition(2)),",",...
                   string(rectifiedPosition(3)),"], Radius = ",...
                   string(app.radiusAtoms(idx))])];
            end
            
            app.InfoText.Value = textInfo(:);
            
            app.drawFaces();
            app.drawEdges();
            
            app.Atoms.Items = {app.textUI(:).String};
            set(app.Atoms,'Value',{});
        end

        % Button pushed function: DrawDirection
        function DrawDirectionButtonPushed(app, event)
            millerD = [app.x.Value,app.y.Value,app.z.Value];
            
            millerDirection(app,millerD,true);
        end

        % Button pushed function: EraseDirection
        function EraseDirectionButtonPushed(app, event)
            for idx = 1:length(app.directions)
                delete(app.directions(idx));
            end
            
            app.directions = [];
        end

        % Button pushed function: DrawPlane
        function DrawPlaneButtonPushed(app, event)
            millerP = [app.j.Value,app.k.Value,app.l.Value];
            
            try 
                delete(app.plane);
            catch ME
                disp(ME);
            end
            
            millerPlane(app,millerP,true);
        end

        % Button pushed function: ErasePlane
        function ErasePlaneButtonPushed(app, event)
            delete(app.plane);
        end

        % Value changed function: ExtrainfoCheckBox
        function ExtrainfoCheckBoxValueChanged(app, event)
            value = app.ExtrainfoCheckBox.Value;
            
            if ~value
                for idx = 1:length(app.extraInfo)
                    set(app.extraInfo(idx), 'Visible', 'off');
                end
            else
                for idx = 1:length(app.extraInfo)
                    set(app.extraInfo(idx), 'Visible', 'on');
                end
            end
        end

        % Value changed function: TextCheckBox
        function TextCheckBoxValueChanged(app, event)
            value = app.TextCheckBox.Value;
            
            if ~value
                for idx = 1:length(app.textUI)
                    set(app.textUI(idx), 'Visible', 'off');
                end
            else
                for idx = 1:length(app.textUI)
                    set(app.textUI(idx), 'Visible', 'on');
                end
            end
        end

        % Selection changed function: ViewModeButtonGroup
        function ViewModeButtonGroupSelectionChanged(app, event)
            drawDefaultAtoms(app);
            selectedAtoms(app);
        end

        % Selection changed function: UnitCellButtonGroup
        function UnitCellButtonGroupSelectionChanged(app, event)
            selectedButton = app.UnitCellButtonGroup.SelectedObject; %Show unit cell volume in different ways
            
            if selectedButton.Text == "None"
                for idx = 1:length(app.faces)
                    set(app.faces(idx), 'Visible', 'off');
                end
                for idx = 1:length(app.edges)
                    set(app.edges(idx), 'Visible', 'off');
                end
            elseif selectedButton.Text == "Edges"
                for idx = 1:length(app.faces)
                    set(app.faces(idx), 'Visible', 'off');
                end
                for idx = 1:length(app.edges)
                    set(app.edges(idx), 'Visible', 'on');
                end
            else
                for idx = 1:length(app.faces)
                    set(app.faces(idx), 'Visible', 'on');
                end
                for idx = 1:length(app.edges)
                    set(app.edges(idx), 'Visible', 'off');
                end
            end
        end

        % Value changed function: Atoms
        function AtomsValueChanged(app, event)
            selectedAtoms(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            set(app.Atoms,'Value',{}); %reverts the selected atoms back to normal
            drawDefaultAtoms(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if app.length_1.Value ~= 0 && app.length_2.Value ~= 0 && app.length_3.Value ~= 0 && ...
            app.angle_1.Value ~= 0 && app.angle_2.Value ~= 0 && app.angle_3.Value ~= 0
                makeTransfMatrix(app);
                drawUnitCell(app);
                app.AtomsSettingsPanel.Enable = 'on';
                app.SavePanel.Enable = 'on';
                app.UnitCellSettingsPanel.Enable = 'off';
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.pos_x.Value >= 0 && app.pos_x.Value <= 1 && app.pos_y.Value >= 0 && app.pos_y.Value <= 1 && ...
            app.pos_z.Value >= 0 && app.pos_z.Value <= 1 && app.r.Value > 0 && ...
            app.NameText.Value ~= ""
                a = app.createTransformation*[app.pos_x.Value;app.pos_y.Value;app.pos_z.Value];
                X = a(1);
                Y = a(2);
                Z = a(3);

                s = eplot(app,app.r.Value, X, Y, Z);
                app.editAtomsPos = [app.editAtomsPos, app.r.Value, X, Y, Z];
                app.editAtomsName = [string(app.editAtomsName), string(app.NameText.Value)];
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.NameText_2.Value ~= ""
                app.createTransformation(:,1) = app.createTransformation(:,1)/app.length_1.Value;
                app.createTransformation(:,2) = app.createTransformation(:,2)/app.length_2.Value;
                app.createTransformation(:,3) = app.createTransformation(:,3)/app.length_3.Value;

                name = app.NameText_2.Value + ".csv";
                writematrix(app.editAtomsName, name)
                writematrix(app.editAtomsPos, name, 'WriteMode', "append");
                writematrix(app.createTransformation, name, 'WriteMode', "append");
                writematrix(app.sideLengths, name, 'WriteMode', "append");
            end
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.AtomsSettingsPanel.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.UnitCellSettingsPanel.Enable = 'on';
            
            app.editAtomsPos = [];
            app.editAtomsName = [];
            app.createEdges = [];
            app.createTransformation = [];
            cla(app.CreateAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.ArrangementsListBoxLabel = uilabel(app.ViewTab);
            app.ArrangementsListBoxLabel.Text = 'Arrangements';
            app.Arrangements = uilistbox(app.ViewTab);
            app.Arrangements.Items = {};
            app.Arrangements.ValueChangedFcn = createCallbackFcn(app, @ArrangementsValueChanged, true);
            app.Arrangements.Value = {};
            app.ViewModeButtonGroup = uibuttongroup(app.ViewTab);
            app.ViewModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewModeButtonGroupSelectionChanged, true);
            app.ViewModeButtonGroup.Title = 'View Mode';
            app.GradientButton = uitogglebutton(app.ViewModeButtonGroup);
            app.GradientButton.Text = 'Gradient';
            app.GradientButton.Value = true;
            app.SolidButton = uitogglebutton(app.ViewModeButtonGroup);
            app.SolidButton.Text = 'Solid';
            app.LowPolyButton = uitogglebutton(app.ViewModeButtonGroup);
            app.LowPolyButton.Text = 'Low Poly';
            app.MillerIndiciesPanel = uipanel(app.ViewTab);
            app.MillerIndiciesPanel.Title = 'Miller Indicies';
            app.MillerDirectionsLabel = uilabel(app.MillerIndiciesPanel);
            app.MillerDirectionsLabel.Text = 'Miller Directions';
            app.MillerPlanesLabel = uilabel(app.MillerIndiciesPanel);
            app.MillerPlanesLabel.Text = 'Miller Planes';
            app.x = uieditfield(app.MillerIndiciesPanel, 'numeric');
            app.y = uieditfield(app.MillerIndiciesPanel, 'numeric');
            app.z = uieditfield(app.MillerIndiciesPanel, 'numeric');
            app.j = uieditfield(app.MillerIndiciesPanel, 'numeric');
            app.k = uieditfield(app.MillerIndiciesPanel, 'numeric');
            app.l = uieditfield(app.MillerIndiciesPanel, 'numeric');
            app.DrawDirection = uibutton(app.MillerIndiciesPanel, 'push');
            app.DrawDirection.ButtonPushedFcn = createCallbackFcn(app, @DrawDirectionButtonPushed, true);
            app.DrawDirection.Text = 'Draw';
            app.EraseDirection = uibutton(app.MillerIndiciesPanel, 'push');
            app.EraseDirection.ButtonPushedFcn = createCallbackFcn(app, @EraseDirectionButtonPushed, true);
            app.EraseDirection.Text = 'Erase';
            app.DrawPlane = uibutton(app.MillerIndiciesPanel, 'push');
            app.DrawPlane.ButtonPushedFcn = createCallbackFcn(app, @DrawPlaneButtonPushed, true);
            app.DrawPlane.Text = 'Draw';
            app.ErasePlane = uibutton(app.MillerIndiciesPanel, 'push');
            app.ErasePlane.ButtonPushedFcn = createCallbackFcn(app, @ErasePlaneButtonPushed, true);
            app.ErasePlane.Text = 'Erase';
            app.ArrangementInformationTextAreaLabel = uilabel(app.ViewTab);
            app.ArrangementInformationTextAreaLabel.Text = 'Arrangement Information';
            app.InfoText = uitextarea(app.ViewTab);
            app.InfoText.Editable = 'off';
            app.ExtrainfoCheckBox = uicheckbox(app.ViewTab);
            app.ExtrainfoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtrainfoCheckBoxValueChanged, true);
            app.ExtrainfoCheckBox.Text = ' Extra info';
            app.ExtrainfoCheckBox.Value = true;
            app.TextCheckBox = uicheckbox(app.ViewTab);
            app.TextCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextCheckBoxValueChanged, true);
            app.TextCheckBox.Text = ' Text';
            app.TextCheckBox.Value = true;
            app.UnitCellButtonGroup = uibuttongroup(app.ViewTab);
            app.UnitCellButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitCellButtonGroupSelectionChanged, true);
            app.UnitCellButtonGroup.Title = 'Unit Cell';
            app.NoneButton = uitogglebutton(app.UnitCellButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.EdgesButton = uitogglebutton(app.UnitCellButtonGroup);
            app.EdgesButton.Text = 'Edges';
            app.FacesButton = uitogglebutton(app.UnitCellButtonGroup);
            app.FacesButton.Text = 'Faces';
            app.AtomLabel = uilabel(app.ViewTab);
            app.AtomLabel.Text = 'Atom';
            app.Atoms = uilistbox(app.ViewTab);
            app.Atoms.Items = {};
            app.Atoms.Multiselect = 'on';
            app.Atoms.ValueChangedFcn = createCallbackFcn(app, @AtomsValueChanged, true);
            app.Atoms.Value = {};
            app.ResetButton = uibutton(app.ViewTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ViewAxes = uiaxes(app.ViewTab);
            app.CreateTab = uitab(app.TabGroup);
            app.CreateTab.Title = 'Create';
            app.AtomsSettingsPanel = uipanel(app.CreateTab);
            app.AtomsSettingsPanel.Enable = 'off';
            app.AtomsSettingsPanel.Title = 'Atoms Settings';
            app.PosittionLabel = uilabel(app.AtomsSettingsPanel);
            app.PosittionLabel.Text = 'Posittion';
            app.RadiusLabel = uilabel(app.AtomsSettingsPanel);
            app.RadiusLabel.Text = 'Radius';
            app.pos_x = uieditfield(app.AtomsSettingsPanel, 'numeric');
            app.pos_y = uieditfield(app.AtomsSettingsPanel, 'numeric');
            app.pos_z = uieditfield(app.AtomsSettingsPanel, 'numeric');
            app.r = uieditfield(app.AtomsSettingsPanel, 'numeric');
            app.r.Value = 1;
            app.AddButton = uibutton(app.AtomsSettingsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.NameLabel = uilabel(app.AtomsSettingsPanel);
            app.NameLabel.Text = 'Name';
            app.NameText = uieditfield(app.AtomsSettingsPanel, 'text');
            app.NameText.Value = 'A';
            app.UnitCellSettingsPanel = uipanel(app.CreateTab);
            app.UnitCellSettingsPanel.Title = 'Unit Cell Settings';
            app.LengthsLabel = uilabel(app.UnitCellSettingsPanel);
            app.LengthsLabel.Text = 'Lengths';
            app.AnglesLabel = uilabel(app.UnitCellSettingsPanel);
            app.AnglesLabel.Text = 'Angles';
            app.length_1 = uieditfield(app.UnitCellSettingsPanel, 'numeric');
            app.length_1.Value = 1;
            app.length_2 = uieditfield(app.UnitCellSettingsPanel, 'numeric');
            app.length_2.Value = 1;
            app.length_3 = uieditfield(app.UnitCellSettingsPanel, 'numeric');
            app.length_3.Value = 1;
            app.angle_1 = uieditfield(app.UnitCellSettingsPanel, 'numeric');
            app.angle_1.Value = 90;
            app.angle_2 = uieditfield(app.UnitCellSettingsPanel, 'numeric');
            app.angle_2.Value = 90;
            app.angle_3 = uieditfield(app.UnitCellSettingsPanel, 'numeric');
            app.angle_3.Value = 90;
            app.SetButton = uibutton(app.UnitCellSettingsPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.SavePanel = uipanel(app.CreateTab);
            app.SavePanel.Enable = 'off';
            app.SavePanel.Title = 'Save';
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NameLabel_2 = uilabel(app.SavePanel);
            app.NameLabel_2.Text = 'Name';
            app.NameText_2 = uieditfield(app.SavePanel, 'text');
            app.NewButton = uibutton(app.CreateTab, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.CreateAxes = uiaxes(app.CreateTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrystalThing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end